classdef PlotterGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        PlotColourDropDown         matlab.ui.control.DropDown
        PlotColourDropDownLabel    matlab.ui.control.Label
        SaveasButton               matlab.ui.control.Button
        LegendCheckBox             matlab.ui.control.CheckBox
        GridCheckBox               matlab.ui.control.CheckBox
        yLableEditField            matlab.ui.control.EditField
        yLableEditFieldLabel       matlab.ui.control.Label
        xLableEditField            matlab.ui.control.EditField
        xLableEditFieldLabel       matlab.ui.control.Label
        PlotTitleEditField         matlab.ui.control.EditField
        PlotTitleEditFieldLabel    matlab.ui.control.Label
        Load_File                  matlab.ui.control.Button
        RightPanel                 matlab.ui.container.Panel
        RangeUpper                 matlab.ui.control.NumericEditField
        RangeLower                 matlab.ui.control.NumericEditField
        FrequencyEditField_2Label  matlab.ui.control.Label
        FineRangeSlider            matlab.ui.control.Slider
        FineRangeSliderLabel       matlab.ui.control.Label
        D                          matlab.ui.control.Button
        UU                         matlab.ui.control.Button
        DD                         matlab.ui.control.Button
        U                          matlab.ui.control.Button
        R                          matlab.ui.control.Button
        RR                         matlab.ui.control.Button
        L                          matlab.ui.control.Button
        LL                         matlab.ui.control.Button
        RRR                        matlab.ui.control.Button
        LLL                        matlab.ui.control.Button
        CentreMHzEditField         matlab.ui.control.NumericEditField
        CentreMHzEditFieldLabel    matlab.ui.control.Label
        CorseRangeSlider           matlab.ui.control.Slider
        CorseRangeSliderLabel      matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Load_File
        function Load_FilePushed(app, event)
            % Button to load file
            [file,path] = uigetfile('*','MultiSelect',"off");
            if isequal(file,0)
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(path,file)]);
            end
            addpath(path)
            Spectrum=load(file);
            Freq=Spectrum(:,1);
            Stren=Spectrum(:,2);
            plot(app.UIAxes,Freq,Stren,'Color',app.PlotColourDropDown.Value);
            title(app.UIAxes,app.PlotTitleEditField.Value);
            xlabel(app.UIAxes,app.xLableEditField.Value);
            ylabel(app.UIAxes,app.yLableEditField.Value);
            app.PlotColourDropDown.Enable=false;
            % rep=str2double(inputdlg("Number(s) of graphs"))
        end

        % Value changed function: GridCheckBox
        function GridCheckBoxValueChanged(app, event)
            value = app.GridCheckBox.Value;
            if value==1
            grid(app.UIAxes,'on')
            else
            grid(app.UIAxes,'off')
            end
        end

        % Button pushed function: SaveasButton
        function SaveasButtonPushed(app, event)
            DPI=600;
            DPI=str2double(inputdlg("Change the default image quality (DPI)"))
            filter = {'*.png';'*.jpg';'*.tif';'*.pdf';'*.eps'};
            [filename,filepath] = uiputfile(filter);
            if ischar(filename)
                exportgraphics(app.UIAxes,[filepath filename],'Resolution',DPI);
            end
        end

        % Value changed function: CentreMHzEditField
        function CentreMHzEditFieldValueChanged(app, event)
            xlim(app.UIAxes,[app.CentreMHzEditField.Value-1 app.CentreMHzEditField.Value+1])
            ylim(app.UIAxes,'auto') 
        end

        % Value changing function: CorseRangeSlider
        function CorseRangeSliderValueChanging(app, event)
            value = app.CentreMHzEditField.Value;
            changingValue = event.Value;
            xlim(app.UIAxes,[value-changingValue value+changingValue])
        end

        % Button pushed function: L
        function LButtonPushed(app, event)
            x=xlim(app.UIAxes)-0.1;
            xlim(app.UIAxes,x)    
        end

        % Button pushed function: LL
        function LLButtonPushed(app, event)
            x=xlim(app.UIAxes)-10;
            xlim(app.UIAxes,x)    
        end

        % Button pushed function: LLL
        function LLLButtonPushed(app, event)
            x=xlim(app.UIAxes)-100;
            xlim(app.UIAxes,x)    
        end

        % Button pushed function: R
        function RButtonPushed(app, event)
            x=xlim(app.UIAxes)+0.1;
            xlim(app.UIAxes,x)    
        end

        % Button pushed function: RR
        function RRButtonPushed(app, event)
            x=xlim(app.UIAxes)+10;
            xlim(app.UIAxes,x)    
        end

        % Button pushed function: RRR
        function RRRButtonPushed(app, event)
            x=xlim(app.UIAxes)+100;
            xlim(app.UIAxes,x)    
        end

        % Value changing function: FineRangeSlider
        function FineRangeSliderValueChanging(app, event)
            value = app.CentreMHzEditField.Value;
            changingValue = event.Value;
            xlim(app.UIAxes,[value-changingValue value+changingValue])
        end

        % Button pushed function: U
        function UButtonPushed(app, event)
            y=ylim(app.UIAxes)+[0,0.001];
            ylim(app.UIAxes,y)    
        end

        % Button pushed function: UU
        function UUButtonPushed(app, event)
            y=ylim(app.UIAxes)+[0,0.01];
            ylim(app.UIAxes,y)    
        end

        % Button pushed function: DD
        function DDButtonPushed(app, event)
            y=ylim(app.UIAxes)-[0,0.01];
            ylim(app.UIAxes,y)    
        end

        % Button pushed function: D
        function DButtonPushed(app, event)
            y=ylim(app.UIAxes)-[0,0.001];
            ylim(app.UIAxes,y)    
        end

        % Value changing function: yLableEditField
        function yLableEditFieldValueChanging(app, event)
            ylabel(app.UIAxes,app.yLableEditField.Value)
        end

        % Value changing function: xLableEditField
        function xLableEditFieldValueChanging(app, event)
            xlabel(app.UIAxes,app.xLableEditField.Value)
        end

        % Value changed function: xLableEditField
        function xLableEditFieldValueChanged(app, event)
            value = app.xLableEditField.Value;
            xlabel(app.UIAxes,value)
        end

        % Value changed function: yLableEditField
        function yLableEditFieldValueChanged(app, event)
            value = app.yLableEditField.Value;
            ylabel(app.UIAxes,value)
        end

        % Value changed function: PlotTitleEditField
        function PlotTitleEditFieldValueChanged(app, event)
            value = app.PlotTitleEditField.Value;
            title(app.UIAxes,value)
        end

        % Value changed function: RangeLower
        function RangeLowerValueChanged(app, event)
            valueL = app.RangeLower.Value;
            xlim(app.UIAxes,[valueL app.RangeUpper.Value])
        end

        % Value changed function: RangeUpper
        function RangeUpperValueChanged(app, event)
            valueU = app.RangeUpper.Value;
            xlim(app.UIAxes,[app.RangeLower.Value valueU])
        end

        % Value changed function: LegendCheckBox
        function LegendCheckBoxValueChanged(app, event)
            value = app.LegendCheckBox.Value;
            if value == 1
                text=inputdlg("Data lable")
                legend(app.UIAxes,text)
            else
                legend(app.UIAxes,"off")
            end
        end

        % Value changed function: PlotColourDropDown
        function PlotColourDropDownValueChanged(app, event)

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {380, 380};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {163, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Load_File = uibutton(app.LeftPanel, 'push');
            app.Load_File.ButtonPushedFcn = createCallbackFcn(app, @Load_FilePushed, true);
            app.Load_File.Text = 'Load File';
            app.PlotTitleEditFieldLabel = uilabel(app.LeftPanel);
            app.PlotTitleEditFieldLabel.Text = 'Plot Title';
            app.PlotTitleEditField = uieditfield(app.LeftPanel, 'text');
            app.PlotTitleEditField.ValueChangedFcn = createCallbackFcn(app, @PlotTitleEditFieldValueChanged, true);
            app.xLableEditFieldLabel = uilabel(app.LeftPanel);
            app.xLableEditFieldLabel.Text = 'x Lable';
            app.xLableEditField = uieditfield(app.LeftPanel, 'text');
            app.xLableEditField.ValueChangedFcn = createCallbackFcn(app, @xLableEditFieldValueChanged, true);
            app.xLableEditField.ValueChangingFcn = createCallbackFcn(app, @xLableEditFieldValueChanging, true);
            app.yLableEditFieldLabel = uilabel(app.LeftPanel);
            app.yLableEditFieldLabel.Text = 'y Lable';
            app.yLableEditField = uieditfield(app.LeftPanel, 'text');
            app.yLableEditField.ValueChangedFcn = createCallbackFcn(app, @yLableEditFieldValueChanged, true);
            app.yLableEditField.ValueChangingFcn = createCallbackFcn(app, @yLableEditFieldValueChanging, true);
            app.GridCheckBox = uicheckbox(app.LeftPanel);
            app.GridCheckBox.ValueChangedFcn = createCallbackFcn(app, @GridCheckBoxValueChanged, true);
            app.GridCheckBox.Text = 'Grid';
            app.LegendCheckBox = uicheckbox(app.LeftPanel);
            app.LegendCheckBox.ValueChangedFcn = createCallbackFcn(app, @LegendCheckBoxValueChanged, true);
            app.LegendCheckBox.Text = 'Legend';
            app.SaveasButton = uibutton(app.LeftPanel, 'push');
            app.SaveasButton.ButtonPushedFcn = createCallbackFcn(app, @SaveasButtonPushed, true);
            app.SaveasButton.Text = 'Save as';
            app.PlotColourDropDownLabel = uilabel(app.LeftPanel);
            app.PlotColourDropDownLabel.Text = 'Plot Colour';
            app.PlotColourDropDown = uidropdown(app.LeftPanel);
            app.PlotColourDropDown.Items = {'red', 'green', 'blue', 'cyan', 'megenta', 'yellow', 'black'};
            app.PlotColourDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotColourDropDownValueChanged, true);
            app.PlotColourDropDown.Value = 'blue';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.CorseRangeSliderLabel = uilabel(app.RightPanel);
            app.CorseRangeSliderLabel.Text = 'Corse Range';
            app.CorseRangeSlider = uislider(app.RightPanel);
            app.CorseRangeSlider.Limits = [10 210];
            app.CorseRangeSlider.ValueChangingFcn = createCallbackFcn(app, @CorseRangeSliderValueChanging, true);
            app.CorseRangeSlider.Value = 10;
            app.CentreMHzEditFieldLabel = uilabel(app.RightPanel);
            app.CentreMHzEditFieldLabel.Text = 'Centre MHz';
            app.CentreMHzEditField = uieditfield(app.RightPanel, 'numeric');
            app.CentreMHzEditField.ValueChangedFcn = createCallbackFcn(app, @CentreMHzEditFieldValueChanged, true);
            app.CentreMHzEditField.Value = 2000;
            app.LLL = uibutton(app.RightPanel, 'push');
            app.LLL.ButtonPushedFcn = createCallbackFcn(app, @LLLButtonPushed, true);
            app.LLL.Text = '<<<';
            app.RRR = uibutton(app.RightPanel, 'push');
            app.RRR.ButtonPushedFcn = createCallbackFcn(app, @RRRButtonPushed, true);
            app.RRR.Text = '>>>';
            app.LL = uibutton(app.RightPanel, 'push');
            app.LL.ButtonPushedFcn = createCallbackFcn(app, @LLButtonPushed, true);
            app.LL.Text = '<<';
            app.L = uibutton(app.RightPanel, 'push');
            app.L.ButtonPushedFcn = createCallbackFcn(app, @LButtonPushed, true);
            app.L.Text = '<';
            app.RR = uibutton(app.RightPanel, 'push');
            app.RR.ButtonPushedFcn = createCallbackFcn(app, @RRButtonPushed, true);
            app.RR.Text = '>>';
            app.R = uibutton(app.RightPanel, 'push');
            app.R.ButtonPushedFcn = createCallbackFcn(app, @RButtonPushed, true);
            app.R.Text = '>';
            app.U = uibutton(app.RightPanel, 'push');
            app.U.ButtonPushedFcn = createCallbackFcn(app, @UButtonPushed, true);
            app.U.Text = '∧';
            app.DD = uibutton(app.RightPanel, 'push');
            app.DD.ButtonPushedFcn = createCallbackFcn(app, @DDButtonPushed, true);
            app.DD.Text = '∨∨';
            app.UU = uibutton(app.RightPanel, 'push');
            app.UU.ButtonPushedFcn = createCallbackFcn(app, @UUButtonPushed, true);
            app.UU.Text = '∧∧';
            app.D = uibutton(app.RightPanel, 'push');
            app.D.ButtonPushedFcn = createCallbackFcn(app, @DButtonPushed, true);
            app.D.Text = '∨';
            app.FineRangeSliderLabel = uilabel(app.RightPanel);
            app.FineRangeSliderLabel.Text = 'Fine Range';
            app.FineRangeSlider = uislider(app.RightPanel);
            app.FineRangeSlider.Limits = [0.01 10.01];
            app.FineRangeSlider.MajorTicks = [0.01 5.01 10.01];
            app.FineRangeSlider.MajorTickLabels = {'0', '5', '10', '15', '20'};
            app.FineRangeSlider.ValueChangingFcn = createCallbackFcn(app, @FineRangeSliderValueChanging, true);
            app.FineRangeSlider.MinorTicks = [0.01 0.51 1.01 1.51 2.01 2.51 3.01 3.51 4.01 4.51 5.01 5.51 6.01 6.51 7.01 7.51 8.01 8.51 9.01 9.51 10.01];
            app.FineRangeSlider.Value = 0.01;
            app.FrequencyEditField_2Label = uilabel(app.RightPanel);
            app.FrequencyEditField_2Label.Text = 'Range MHz';
            app.RangeLower = uieditfield(app.RightPanel, 'numeric');
            app.RangeLower.ValueChangedFcn = createCallbackFcn(app, @RangeLowerValueChanged, true);
            app.RangeLower.Value = 2000;
            app.RangeUpper = uieditfield(app.RightPanel, 'numeric');
            app.RangeUpper.ValueChangedFcn = createCallbackFcn(app, @RangeUpperValueChanged, true);
            app.RangeUpper.Value = 8000;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlotterGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end